classdef NeuroTrainSmr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        SpectrogramPanel             matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        averageEditField             matlab.ui.control.NumericEditField
        averagesEditFieldLabel       matlab.ui.control.Label
        innershiftEditField          matlab.ui.control.NumericEditField
        innershiftsEditFieldLabel    matlab.ui.control.Label
        outershiftEditField          matlab.ui.control.NumericEditField
        outershiftsEditFieldLabel    matlab.ui.control.Label
        windowlengthEditField        matlab.ui.control.NumericEditField
        windowlengthsEditFieldLabel  matlab.ui.control.Label
        PreprocessingPanel           matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        LaplacianCheckBox            matlab.ui.control.CheckBox
        CARCheckBox                  matlab.ui.control.CheckBox
        DCremovalCheckBox            matlab.ui.control.CheckBox
        OptionsPanel                 matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        TrainonalldataCheckBox       matlab.ui.control.CheckBox
        RecomputePSDCheckBox         matlab.ui.control.CheckBox
        SavePSDCheckBox              matlab.ui.control.CheckBox
        GridLayout8                  matlab.ui.container.GridLayout
        TrainButton                  matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        NeuroFileSelector            NeuroFileSelector
    end

    
    properties (Access = public)
        settings % Settings for the train
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function init_gui_components(app, settings)
            app.DCremovalCheckBox.Value = settings.processing.filters.dc.enable;
            app.CARCheckBox.Value       = settings.processing.filters.car.enable;
            app.LaplacianCheckBox.Value = settings.processing.filters.laplacian.enable;

            app.averageEditField.Value      = settings.processing.psd.mlength;
            app.outershiftEditField.Value   = settings.processing.psd.wshift;
            app.innershiftEditField.Value   = settings.processing.psd.pshift;
            app.windowlengthEditField.Value = settings.processing.psd.wlength;

            app.SavePSDCheckBox.Value      = settings.storage.save;
            app.RecomputePSDCheckBox.Value = settings.storage.recompute;

            app.TrainonalldataCheckBox.Value = settings.classification.train_on_all; 

          
        end
        
        
        function info = get_gdf_info(~, files)

            info = [];

            if isempty(files) 
                return
            end

            
            for fId = 1:length(files) 
                h = neuroio_import_header(files{fId});
                cinfo.samplerate = h.SampleRate;
                cinfo.labels = h.Label;
                info = cat(1, info, cinfo);
            end

        end
        
        function reset_data_info(app)
            app.SampleRateHzEditField.Value = 0;
            app.SampleRateHzEditField.FontColor = 'k'; 
            app.DatachannelsEditField.Value = 0;
            app.DatachannelsEditField.FontColor = 'k';
            app.LayoutDropDown.Enable = 'off';
            app.LayoutShow.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Load default settings
            app.settings = neuro_smr_default_settings;

            % Initialize GUI elements with default settings
            init_gui_components(app, app.settings);
        end

        % Value changed function: averageEditField
        function averageEditFieldValueChanged(app, event)
            app.settings.processing.psd.mlength = app.averageEditField.Value;
        end

        % Value changed function: innershiftEditField
        function innershiftEditFieldValueChanged(app, event)
            app.settings.processing.psd.pshift = app.innershiftEditField.Value;           
        end

        % Value changed function: outershiftEditField
        function outershiftEditFieldValueChanged(app, event)
            app.settings.processing.psd.wshift = app.outershiftEditField.Value;
        end

        % Value changed function: windowlengthEditField
        function windowlengthEditFieldValueChanged(app, event)
            app.settings.processing.psd.wlength = app.windowlengthEditField.Value;        
        end

        % Value changed function: RecomputePSDCheckBox
        function RecomputePSDCheckBoxValueChanged(app, event)
            app.settings.storage.recompute = app.RecomputePSDCheckBox.Value;
        end

        % Value changed function: SavePSDCheckBox
        function SavePSDCheckBoxValueChanged(app, event)
            app.settings.storage.save = app.SavePSDCheckBox.Value;
        end

        % Value changed function: LaplacianCheckBox
        function LaplacianCheckBoxValueChanged(app, event)
            app.settings.processing.filters.laplacian.enable = app.LaplacianCheckBox.Value;
        end

        % Value changed function: CARCheckBox
        function CARCheckBoxValueChanged(app, event)
            app.settings.processing.filters.car.enable = app.CARCheckBox.Value;
        end

        % Value changed function: DCremovalCheckBox
        function DCremovalCheckBoxValueChanged(app, event)
            app.settings.processing.filters.dc.enable = app.DCremovalCheckBox.Value;
        end

        % Value changed function: TrainonalldataCheckBox
        function TrainonalldataCheckBoxValueChanged(app, event)
            app.settings.classification.train_on_all = app.TrainonalldataCheckBox.Value;           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Load default settings
            app.settings = neuro_smr_default_settings;

            % Initialize GUI elements with default settings
            init_gui_components(app, app.settings);

            % Reset selected files in browser
            app.NeuroFileSelector.reset_selected();        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NeuroFileSelector = NeuroFileSelector(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.ResetButton = uibutton(app.GridLayout8, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ProcessButton = uibutton(app.GridLayout8, 'push');
            app.ProcessButton.Text = 'Process';
            app.TrainButton = uibutton(app.GridLayout8, 'push');
            app.TrainButton.Text = 'Train';
            app.OptionsPanel = uipanel(app.GridLayout7);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout5 = uigridlayout(app.OptionsPanel);
            app.SavePSDCheckBox = uicheckbox(app.GridLayout5);
            app.SavePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePSDCheckBoxValueChanged, true);
            app.SavePSDCheckBox.Text = 'Save PSD';
            app.RecomputePSDCheckBox = uicheckbox(app.GridLayout5);
            app.RecomputePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputePSDCheckBoxValueChanged, true);
            app.RecomputePSDCheckBox.Text = 'Recompute PSD';
            app.TrainonalldataCheckBox = uicheckbox(app.GridLayout5);
            app.TrainonalldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainonalldataCheckBoxValueChanged, true);
            app.TrainonalldataCheckBox.Text = 'Train on all data';
            app.PreprocessingPanel = uipanel(app.GridLayout7);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.GridLayout3 = uigridlayout(app.PreprocessingPanel);
            app.DCremovalCheckBox = uicheckbox(app.GridLayout3);
            app.DCremovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCremovalCheckBoxValueChanged, true);
            app.DCremovalCheckBox.Text = 'DC removal';
            app.CARCheckBox = uicheckbox(app.GridLayout3);
            app.CARCheckBox.ValueChangedFcn = createCallbackFcn(app, @CARCheckBoxValueChanged, true);
            app.CARCheckBox.Text = 'CAR';
            app.LaplacianCheckBox = uicheckbox(app.GridLayout3);
            app.LaplacianCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaplacianCheckBoxValueChanged, true);
            app.LaplacianCheckBox.Enable = 'off';
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.SpectrogramPanel = uipanel(app.GridLayout7);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.GridLayout4 = uigridlayout(app.SpectrogramPanel);
            app.windowlengthsEditFieldLabel = uilabel(app.GridLayout4);
            app.windowlengthsEditFieldLabel.Text = 'window length [s]:';
            app.windowlengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.windowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @windowlengthEditFieldValueChanged, true);
            app.outershiftsEditFieldLabel = uilabel(app.GridLayout4);
            app.outershiftsEditFieldLabel.Text = 'outer shift [s]:';
            app.outershiftEditField = uieditfield(app.GridLayout4, 'numeric');
            app.outershiftEditField.ValueChangedFcn = createCallbackFcn(app, @outershiftEditFieldValueChanged, true);
            app.innershiftsEditFieldLabel = uilabel(app.GridLayout4);
            app.innershiftsEditFieldLabel.Text = 'inner shift [s]:';
            app.innershiftEditField = uieditfield(app.GridLayout4, 'numeric');
            app.innershiftEditField.ValueChangedFcn = createCallbackFcn(app, @innershiftEditFieldValueChanged, true);
            app.averagesEditFieldLabel = uilabel(app.GridLayout4);
            app.averagesEditFieldLabel.Text = 'average [s]:';
            app.averageEditField = uieditfield(app.GridLayout4, 'numeric');
            app.averageEditField.ValueChangedFcn = createCallbackFcn(app, @averageEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroTrainSmr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end